classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ApplyButton             matlab.ui.control.Button
        TypeDropDownLabel       matlab.ui.control.Label
        S1TypeDropDown          matlab.ui.control.DropDown
        S1Label                 matlab.ui.control.Label
        OutputLabel             matlab.ui.control.Label
        S1EditFieldOutput       matlab.ui.control.NumericEditField
        S1UnitsOutputLabel      matlab.ui.control.Label
        aLabel                  matlab.ui.control.Label
        S1EditFieldA            matlab.ui.control.NumericEditField
        bLabel                  matlab.ui.control.Label
        S1EditFieldB            matlab.ui.control.NumericEditField
        S1UnitsALabel           matlab.ui.control.Label
        S1UnitsBLabel           matlab.ui.control.Label
        ApplyStatus             matlab.ui.control.Label
        S2UnitsBLabel           matlab.ui.control.Label
        S2UnitsALabel           matlab.ui.control.Label
        bLabel_2                matlab.ui.control.Label
        S2EditFieldB            matlab.ui.control.NumericEditField
        aLabel_2                matlab.ui.control.Label
        S2EditFieldA            matlab.ui.control.NumericEditField
        S2UnitsOutputLabel      matlab.ui.control.Label
        OutputLabel_2           matlab.ui.control.Label
        S2EditFieldOutput       matlab.ui.control.NumericEditField
        TypeDropDownLabel_2     matlab.ui.control.Label
        S2TypeDropDown          matlab.ui.control.DropDown
        S2Label                 matlab.ui.control.Label
        S1EnableSensor          matlab.ui.control.CheckBox
        S2EnableSensor          matlab.ui.control.CheckBox
        ResetSensorInformation  matlab.ui.control.Button
        SensorResetStatus       matlab.ui.control.Label
    end


    methods (Access = private)
    
        function SensorDropDownValueChanged(app, sensor, dropdown, EditField, unitsOutput, FieldA, FieldB)
            value = dropdown.Value;
            if strcmp(value,'Analogue') % compare dropdown value
                EditField.Enable = 'on';
                unitsOutput.Text = 'Volt';
                unitsOutput.Visible = 'on';
                FieldA.Enable = 'on';
                FieldB.Enable = 'on';
            elseif strcmp(value,'Digital') % compare dropdown value
                EditField.Enable = 'on';
                unitsOutput.Text = 'P/min';
                FieldA.Enable = 'off';
                FieldB.Enable = 'off';
            else % error case
                warning('Sensor '+ sensor + ' dropdown error!');
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ApplyStatus.Visible = 'off';
            app.SensorResetStatus.Visible = 'off';
        end

        % Value changed function: S1TypeDropDown
        function S1TypeDropDownValueChanged(app, event)
            SensorDropDownValueChanged(app, ...
                '1', ...
                app.S1TypeDropDown, ...
                app.S1EditFieldOutput, ...
                app.S1UnitsOutputLabel, ...
                app.S1EditFieldA, ...
                app.S1EditFieldB);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            success = true;
            if app.S1EnableSensor.Value
                if strcmp(app.S1TypeDropDown.Value, 'Analogue') % compare dropdown value
                     if ~setSensor(app.S1TypeDropDown.Value, 1, app.S1EditFieldOutput.Value, ...
                             app.S1EditFieldA.Value, app.S1EditFieldB.Value)
                         success = false; % only set success to false when a failure occurs.
                     end
                elseif strcmp(app.S1TypeDropDown.Value, 'Digital') % compare dropdown value
                    if ~setSensor(app.S1TypeDropDown.Value, 1, app.S1EditFieldOutput.Value)
                        success = false; % only set success to false when a failure occurs.
                    end
                end
            end
            
            if app.S2EnableSensor.Value
                if strcmp(app.S2TypeDropDown.Value, 'Analogue') % compare dropdown value
                     if ~setSensor(app.S2TypeDropDown.Value, 2, app.S2EditFieldOutput.Value, ...
                             app.S2EditFieldA.Value, app.S2EditFieldB.Value)
                         success = false; % only set success to false when a failure occurs.
                     end
                elseif strcmp(app.S2TypeDropDown.Value, 'Digital') % compare dropdown value
                    if ~setSensor(app.S2TypeDropDown.Value, 2, app.S2EditFieldOutput.Value)
                        success = false; % only set success to false when a failure occurs.
                    end
                end
            end
            
            app.ApplyStatus.Visible = 'on';
            if success
                app.ApplyStatus.Text = 'Success!';
            else
                app.ApplyStatus.Text = 'Failed!';
            end
        end

        % Value changed function: S2TypeDropDown
        function S2TypeDropDownValueChanged(app, event)
            SensorDropDownValueChanged(app, ...
                '2', ...
                app.S2TypeDropDown, ...
                app.S2EditFieldOutput, ...
                app.S2UnitsOutputLabel, ...
                app.S2EditFieldA, ...
                app.S2EditFieldB);
        end

        % Value changed function: S1EnableSensor
        function S1EnableSensorValueChanged(app, event)
            if app.S1EnableSensor.Value
                app.S1TypeDropDown.Enable = 'on';
                app.S1EditFieldOutput.Enable = 'on';
                app.S1EditFieldB.Enable = 'on';
                app.S1EditFieldA.Enable = 'on';
            else
                app.S1TypeDropDown.Enable = 'off';
                app.S1EditFieldOutput.Enable = 'off';
                app.S1EditFieldB.Enable = 'off';
                app.S1EditFieldA.Enable = 'off';
            end
        end

        % Value changed function: S2EnableSensor
        function S2EnableSensorValueChanged(app, event)
            if app.S2EnableSensor.Value
                app.S2TypeDropDown.Enable = 'on';
                app.S2EditFieldOutput.Enable = 'on';
                app.S2EditFieldB.Enable = 'on';
                app.S2EditFieldA.Enable = 'on';
            else
                app.S2TypeDropDown.Enable = 'off';
                app.S2EditFieldOutput.Enable = 'off';
                app.S2EditFieldB.Enable = 'off';
                app.S2EditFieldA.Enable = 'off';
            end
        end

        % Button pushed function: ResetSensorInformation
        function ResetSensorInformationButtonPushed(app, event)
            app.SensorResetStatus.Visible = 'on';
            if evalin('base', 'exist(''Sensors'')')
                evalin('base', 'clear Sensors'); % Delete the previous sensor information
                if evalin('base', 'exist(''Sensors'')')
                    app.SensorResetStatus.Text = 'Failed!';
                    warning('Failed to remove old instance of sensor information.');
                else
                    app.SensorResetStatus.Text = 'Success!';
                end
            else
                app.SensorResetStatus.Text = 'Success!';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type:';
            app.S1TypeDropDown = uidropdown(app.UIFigure);
            app.S1TypeDropDown.Items = {'Analogue', 'Digital'};
            app.S1TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @S1TypeDropDownValueChanged, true);
            app.S1TypeDropDown.Value = 'Analogue';
            app.S1Label = uilabel(app.UIFigure);
            app.S1Label.Text = 'Sensor 1';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output:';
            app.S1EditFieldOutput = uieditfield(app.UIFigure, 'numeric');
            app.S1EditFieldOutput.Limits = [0 Inf];
            app.S1UnitsOutputLabel = uilabel(app.UIFigure);
            app.S1UnitsOutputLabel.Text = 'Volt';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a:';
            app.S1EditFieldA = uieditfield(app.UIFigure, 'numeric');
            app.S1EditFieldA.Limits = [0 Inf];
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b:';
            app.S1EditFieldB = uieditfield(app.UIFigure, 'numeric');
            app.S1EditFieldB.Limits = [0 Inf];
            app.S1UnitsALabel = uilabel(app.UIFigure);
            app.S1UnitsALabel.Text = 'V/unit';
            app.S1UnitsBLabel = uilabel(app.UIFigure);
            app.S1UnitsBLabel.Text = 'Volt';
            app.ApplyStatus = uilabel(app.UIFigure);
            app.ApplyStatus.Text = 'Success!';
            app.S2UnitsBLabel = uilabel(app.UIFigure);
            app.S2UnitsBLabel.Text = 'Volt';
            app.S2UnitsALabel = uilabel(app.UIFigure);
            app.S2UnitsALabel.Text = 'V/unit';
            app.bLabel_2 = uilabel(app.UIFigure);
            app.bLabel_2.Text = 'b:';
            app.S2EditFieldB = uieditfield(app.UIFigure, 'numeric');
            app.S2EditFieldB.Limits = [0 Inf];
            app.aLabel_2 = uilabel(app.UIFigure);
            app.aLabel_2.Text = 'a:';
            app.S2EditFieldA = uieditfield(app.UIFigure, 'numeric');
            app.S2EditFieldA.Limits = [0 Inf];
            app.S2UnitsOutputLabel = uilabel(app.UIFigure);
            app.S2UnitsOutputLabel.Text = 'Volt';
            app.OutputLabel_2 = uilabel(app.UIFigure);
            app.OutputLabel_2.Text = 'Output:';
            app.S2EditFieldOutput = uieditfield(app.UIFigure, 'numeric');
            app.S2EditFieldOutput.Limits = [0 Inf];
            app.TypeDropDownLabel_2 = uilabel(app.UIFigure);
            app.TypeDropDownLabel_2.Text = 'Type:';
            app.S2TypeDropDown = uidropdown(app.UIFigure);
            app.S2TypeDropDown.Items = {'Analogue', 'Digital'};
            app.S2TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @S2TypeDropDownValueChanged, true);
            app.S2TypeDropDown.Value = 'Analogue';
            app.S2Label = uilabel(app.UIFigure);
            app.S2Label.Text = 'Sensor 2';
            app.S1EnableSensor = uicheckbox(app.UIFigure);
            app.S1EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S1EnableSensorValueChanged, true);
            app.S1EnableSensor.Text = 'Enable';
            app.S1EnableSensor.Value = true;
            app.S2EnableSensor = uicheckbox(app.UIFigure);
            app.S2EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S2EnableSensorValueChanged, true);
            app.S2EnableSensor.Text = 'Enable';
            app.S2EnableSensor.Value = true;
            app.ResetSensorInformation = uibutton(app.UIFigure, 'push');
            app.ResetSensorInformation.ButtonPushedFcn = createCallbackFcn(app, @ResetSensorInformationButtonPushed, true);
            app.ResetSensorInformation.Text = 'Reset sensors';
            app.SensorResetStatus = uilabel(app.UIFigure);
            app.SensorResetStatus.Text = 'Success!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end